classdef ComplexGraphing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DomainColoringTab             matlab.ui.container.Tab
        ReOffsetEditField             matlab.ui.control.NumericEditField
        ReOffsetEditFieldLabel        matlab.ui.control.Label
        ImOffsetEditField             matlab.ui.control.NumericEditField
        ImOffsetEditFieldLabel        matlab.ui.control.Label
        MoveCenterSwitch              matlab.ui.control.Switch
        MoveCenterSwitchLabel         matlab.ui.control.Label
        CommonFunctionsDropDown       matlab.ui.control.DropDown
        CommonFunctionsDropDownLabel  matlab.ui.control.Label
        FunctionzEditField            matlab.ui.control.EditField
        FunctionzLabel                matlab.ui.control.Label
        YScaleEditField               matlab.ui.control.NumericEditField
        YScaleEditFieldLabel          matlab.ui.control.Label
        ResolutionEditFieldLabel      matlab.ui.control.Label
        ResolutionEditField           matlab.ui.control.NumericEditField
        XScaleEditField               matlab.ui.control.NumericEditField
        XScaleEditFieldLabel          matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Tab2                          matlab.ui.container.Tab
    end

    % Brycen Daniels, Gabriel Cammack-Coleman, and Cameron Miller
    properties (Access = private)
        L = 0;
        Rx = 0;
        Ry = 0;
        func;
        ImOff = 0;
        ReOff = 0;
    end
    
    methods (Access = private)
        
        function DomianColor(app, L , Rx, Ry)

            xlim(app.UIAxes,'tight');
            ylim(app.UIAxes, 'tight');

            % sets up variables and functions for the rest of the code
            f = app.func;
            x=linspace(-Rx,Rx,L);
            y=linspace(Ry,-Ry,L);
            [X,Y] = meshgrid(x,y);
            Z=f((X+app.ReOff) + 1i.*(Y+app.ImOff));
            x=[-Rx Rx];
            y=[-Ry Ry];
        
            % argument and magnitude for every point in Z
            argZ=(angle(Z)+pi)/(2.*pi);
            magZ=mod(0.5.*log(1+real(Z).^2+imag(Z).^2),1)./1;
        
            % turns the arg and mag values into a set of colormaps
            argZmap=ind2rgb(uint8(255.*argZ),hsv);
            magZmap=ind2rgb(uint8(255.*magZ),gray);
        
            % plots the final result
            imagesc(x,-y,magZmap,"AlphaData",0.8,'Parent',app.UIAxes)
            hold(app.UIAxes, 'on')
            imagesc(x,-y,argZmap,"AlphaData",0.55,'Parent',app.UIAxes)
            app.UIAxes.YDir = 'normal';
            hold(app.UIAxes, 'off')
            end
            
            function startUp(app)
                axis(app.UIAxes, 'image');
        end
        
        function g=gamma(~, z)
            c=[24309.2517827 -67451.3794262 71084.7561084 -35575.0088977 ...
            8642.23730492 -931.808427232 35.2240350789 -0.265389291384 ...
            0.0000674177040788 0];
            sum=0;
            for i=1:9
                sum = sum + c(i)./(z+i);
            end
            g=(z+10).^(z+0.5).*exp(-z-10).*(sqrt(2.*pi)+sum);
        end

        % fast zeta function approximation for complex values
        function z=zeta(~, s)
            sum=0;
            for n=0:20
                for k=0:n
                    sum=sum+(-1).^(k).*nchoosek(n,k)./(2.^(n+1).*(k+1).^(s));
                end
            end
            z=sum./(1-2.^(1-s));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.L = value;
            
        end

        % Value changed function: XScaleEditField
        function XScaleEditFieldValueChanged(app, event)
            value = app.XScaleEditField.Value;
            app.Rx= value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if(app.FunctionzEditField.Value ~= "" || app.CommonFunctionsDropDown.Value ~= "Select") 
                DomianColor(app, app.L,app.Rx, app.Ry);
            end
        end

        % Value changed function: YScaleEditField
        function YScaleEditFieldValueChanged(app, event)
            value = app.YScaleEditField.Value;
            app.Ry= value;
        end

        % Value changed function: FunctionzEditField
        function FunctionzEditFieldValueChanged(app, event)
            if(app.FunctionzEditField.Value ~= "") 
                app.func = str2func(['@(z) ' app.FunctionzEditField.Value ]);
            end
        end

        % Value changed function: CommonFunctionsDropDown
        function CommonFunctionsDropDownValueChanged(app, event)
            switch app.CommonFunctionsDropDown.Value
                case 'Select'
                    if(app.FunctionzEditField.Value ~= "") 
                       app.func = str2func(['@(z) ' app.FunctionzEditField.Value ]);
                    end
                case 'Gamma'
                    app.func = @(z) gamma(app, z);
                case 'Zeta'
                    app.func = @(z) zeta(app, z);
                    
            end
        end

        % Value changed function: MoveCenterSwitch
        function MoveCenterSwitchValueChanged(app, event)
            value = app.MoveCenterSwitch.Value;
            if (value == "Off")
                app.ReOffsetEditField.Visible = 'off';
                app.ReOffsetEditFieldLabel.Visible = 'off';
                app.ImOffsetEditField.Visible = 'off';
                app.ImOffsetEditFieldLabel.Visible = 'off';
            else
                app.ReOffsetEditField.Visible = 'on';
                app.ReOffsetEditFieldLabel.Visible = 'on';
                app.ImOffsetEditField.Visible = 'on';
                app.ImOffsetEditFieldLabel.Visible = 'on';
            end
        end

        % Value changed function: ReOffsetEditField
        function ReOffsetEditFieldValueChanged(app, event)
            value = app.ReOffsetEditField.Value;
            app.ReOff = value;
        end

        % Value changed function: ImOffsetEditField
        function ImOffsetEditFieldValueChanged(app, event)
            value = app.ImOffsetEditField.Value;
            app.ImOff = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DomainColoringTab = uitab(app.TabGroup);
            app.DomainColoringTab.Title = 'Domain Coloring';
            app.UIAxes = uiaxes(app.DomainColoringTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Real')
            ylabel(app.UIAxes, 'Imaginary')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.DomainColoringTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.XScaleEditFieldLabel = uilabel(app.DomainColoringTab);
            app.XScaleEditFieldLabel.Text = 'X-Scale';
            app.XScaleEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.XScaleEditField.ValueChangedFcn = createCallbackFcn(app, @XScaleEditFieldValueChanged, true);
            app.ResolutionEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditFieldLabel = uilabel(app.DomainColoringTab);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.YScaleEditFieldLabel = uilabel(app.DomainColoringTab);
            app.YScaleEditFieldLabel.Text = 'Y-Scale';
            app.YScaleEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.YScaleEditField.ValueChangedFcn = createCallbackFcn(app, @YScaleEditFieldValueChanged, true);
            app.FunctionzLabel = uilabel(app.DomainColoringTab);
            app.FunctionzLabel.Text = 'Function (z):';
            app.FunctionzEditField = uieditfield(app.DomainColoringTab, 'text');
            app.FunctionzEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionzEditFieldValueChanged, true);
            app.CommonFunctionsDropDownLabel = uilabel(app.DomainColoringTab);
            app.CommonFunctionsDropDownLabel.Text = 'Common Functions';
            app.CommonFunctionsDropDown = uidropdown(app.DomainColoringTab);
            app.CommonFunctionsDropDown.Items = {'Select', 'Gamma', 'Zeta'};
            app.CommonFunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @CommonFunctionsDropDownValueChanged, true);
            app.CommonFunctionsDropDown.Value = 'Select';
            app.MoveCenterSwitchLabel = uilabel(app.DomainColoringTab);
            app.MoveCenterSwitchLabel.Text = 'Move Center';
            app.MoveCenterSwitch = uiswitch(app.DomainColoringTab, 'slider');
            app.MoveCenterSwitch.ValueChangedFcn = createCallbackFcn(app, @MoveCenterSwitchValueChanged, true);
            app.ImOffsetEditFieldLabel = uilabel(app.DomainColoringTab);
            app.ImOffsetEditFieldLabel.Visible = 'off';
            app.ImOffsetEditFieldLabel.Text = 'Im Offset';
            app.ImOffsetEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.ImOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @ImOffsetEditFieldValueChanged, true);
            app.ImOffsetEditField.Visible = 'off';
            app.ReOffsetEditFieldLabel = uilabel(app.DomainColoringTab);
            app.ReOffsetEditFieldLabel.Visible = 'off';
            app.ReOffsetEditFieldLabel.Text = 'Re Offset';
            app.ReOffsetEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.ReOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @ReOffsetEditFieldValueChanged, true);
            app.ReOffsetEditField.Visible = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComplexGraphing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end