classdef ComplexGraphingFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DomainColoringTab               matlab.ui.container.Tab
        GraphigtimeEditField            matlab.ui.control.EditField
        GraphigtimeEditFieldLabel       matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        PolesSwitch                     matlab.ui.control.Switch
        PolesSwitchLabel                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        RootandpolefindingSwitch        matlab.ui.control.Switch
        RootandpolefindingSwitchLabel   matlab.ui.control.Label
        AdvancedButton                  matlab.ui.control.Button
        CommonFunctionsDropDown         matlab.ui.control.DropDown
        CommonFunctionsDropDownLabel    matlab.ui.control.Label
        FunctionzEditField              matlab.ui.control.EditField
        FunctionzLabel                  matlab.ui.control.Label
        ImaginaryScaleEditField         matlab.ui.control.NumericEditField
        ImaginaryScaleEditFieldLabel    matlab.ui.control.Label
        RealScaleEditField              matlab.ui.control.NumericEditField
        RealScaleEditFieldLabel         matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ComplexSurfacesTab              matlab.ui.container.Tab
        GraphingtimeEditField           matlab.ui.control.EditField
        GraphingtimeEditFieldLabel      matlab.ui.control.Label
        AdvancedButton_2                matlab.ui.control.Button
        CommonFunctionsDropDown_2       matlab.ui.control.DropDown
        CommonFunctionsDropDown_2Label  matlab.ui.control.Label
        FunctionzEditField_2            matlab.ui.control.EditField
        FunctionzLabel_2                matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        ImaginaryScaleEditField_2       matlab.ui.control.NumericEditField
        ImaginaryScaleEditField_2Label  matlab.ui.control.Label
        RealScaleEditField_2            matlab.ui.control.NumericEditField
        RealScaleEditField_2Label       matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        HelpTab                         matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        ExamplesTab                     matlab.ui.container.Tab
        logzTextArea_2                  matlab.ui.control.TextArea
        logzTextArea_2Label             matlab.ui.control.Label
        z21TextArea                     matlab.ui.control.TextArea
        z21TextAreaLabel                matlab.ui.control.Label
        logzTextArea                    matlab.ui.control.TextArea
        logzTextAreaLabel               matlab.ui.control.Label
        TryItButton_3                   matlab.ui.control.Button
        TryItButton_2                   matlab.ui.control.Button
        TryItButton                     matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
    end

    % Brycen Daniels, Gabriel Cammack-Coleman, and Cameron Miller


    properties (Access = public)
        L = 1000; %Resoltion for domain coloring
        Rx = 10; %Real scale for domain coloring
        Ry = 10; %Imaginary scale for domain coloring
        Res = 500; %Resoltion for complex surfaces
        Rx2 = 10; %Real scale for complex surfaces
        Ry2 = 10; %Imaginary scale for complex surfaces

        func; %Function for domain coloring
        func2; %Function for complex surfaces

        ImOff = 0; 
        ReOff = 0;
        usedOff = 0;

        CSimOff = 0;
        CSreOff = 0;

        ZMin = 0; %Lower bound for z axis
        ZMax = 0; %Upper bound for z axis
        
        DomainColorVar = 1; %Color variation for domain coloring
        aTanColor = 1; %Controls if color with atan is on or off
        otherApp; %Stores the other app as a variable

        width = 10;%Width of box for root and pole finding
        height = 10;%Height of box for root and pole finding

        rStep = 1; %changes where magnatude lines are drawn
    end
    
    methods (Access = private)
        
        function DomianColor(app, L , Rx, Ry)
            try
                tic
                xlim(app.UIAxes,'tight');
                ylim(app.UIAxes, 'tight');
               
                % sets up variables and functions for the rest of the code
                f = app.func;
                x=linspace(-Rx,Rx,L);
                y=linspace(Ry,-Ry,L);
                [X,Y] = meshgrid(x,y);

                %evaluates the function
                Z=f((X+app.ReOff) + 1i.*(Y+app.ImOff));

                x=[-Rx Rx];
                y=[-Ry Ry];
                
                %Argument and magnitude for every point in Z
                argZ=(angle(Z)+pi)/(2.*pi);
                if app.DomainColorVar == 1
                    magZ=mod(0.5.*log(1+real(Z).^2+imag(Z).^2),app.rStep)./app.rStep; 
                elseif app.DomainColorVar == 2 
                    magZ=mod(log(abs(Z)),app.rStep)./app.rStep;
                elseif app.DomainColorVar == 3
                    M=max(0.5.*log(1+real(Z).^2+imag(Z).^2));
                    magZ=0.5.*log(1+real(Z).^2+imag(Z).^2)./M;
                end
            
                %turns the arg and mag values into a set of colormaps
                argZmap=ind2rgb(uint8(255.*argZ),hsv);
                magZmap=ind2rgb(uint8(255.*magZ),gray);
            
                % plots the final result
                imagesc(x,-y,magZmap,"AlphaData",0.8,'Parent',app.UIAxes)
                hold(app.UIAxes, 'on')
                imagesc(x,-y,argZmap,"AlphaData",0.55,'Parent',app.UIAxes)
                app.UIAxes.YDir = 'normal';

                %fixes axes when center shift is used
                if (app.usedOff == 1)
                    set(app.UIAxes, 'XTickLabel', get(app.UIAxes, 'XTick') + app.ReOff)
                    set(app.UIAxes, 'YTickLabel', get(app.UIAxes, 'YTick') + app.ImOff)
                end
                
                %Create color bar
                hold(app.UIAxes, 'off')
                colorbar(app.UIAxes,'Ticks',[0 0.25 0.5 0.75 1], ...
                'TickLabels',{'-π' '-π/2' '0' 'π/2' 'π'})
                colormap(app.UIAxes, hsv);
                
                %Graphing time
                time = toc;
                Message = sprintf('%f seconds\n',time);
                app.GraphigtimeEditField.Value = Message;
            catch

            end
        end
        

        function startUp(app)
            axis(app.UIAxes, 'image');
        end
        
        %gamma function approximation for complex values
        function g=gamma(~, z)
            c=[24309.2517827 -67451.3794262 71084.7561084 -35575.0088977 ...
            8642.23730492 -931.808427232 35.2240350789 -0.265389291384 ...
            0.0000674177040788 0];
            sum=0;
            for i=1:9
                sum = sum + c(i)./(z+i);
            end
            g=(z+10).^(z+0.5).*exp(-z-10).*(sqrt(2.*pi)+sum);
        end

        %fast zeta function approximation for complex values
        function z=Rzeta(~, s)
            sum=0;
            for n=0:20
                for k=0:n
                    sum=sum+(-1).^(k).*nchoosek(n,k)./(2.^(n+1).*(k+1).^(s));
                end
            end
            z=sum./(1-2.^(1-s));
        end

        % Res changes how many subsections of the plane are used to plot the
        % graph. Rx2 changes the scale of the real axis to -Rx2 to
        % Ry2. Ry2 changes the scale of the imaginary axis to -Ry2*i to
        % Ry2*i
        function complexSurf(app, Res, Rx2, Ry2)
            try
                %Sets up variables and evaluates the function
                tic
                a=linspace(-Rx2 + app.CSreOff,Rx2 + app.CSreOff,Res);
                b=linspace(-Ry2 + app.CSimOff,Ry2 + app.CSimOff,Res);
                [X,Y]=meshgrid(a,b);
                Z = app.func2;
                W=Z(X+1i.*Y);
                C1=imag(W);
                C2=real(W);
                L1=atan(C1);
                L2=atan(C2);

                %Check if the z-bounds are being used and check that they
                %are entered correctly
                if(app.ZMin == 0 && app.ZMax == 0)
                    app.ZMin = -inf;
                    app.ZMax = inf;
                elseif(app.ZMin == app.ZMax)
                    message = {'Z-Min can not be equal to Z-Max'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                    app.ZMin = -inf;
                    app.ZMax = inf;
                elseif(app.ZMin > app.ZMax)
                    message = {'Z-Min must be less than Z-Max'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                    app.ZMin = -inf;
                    app.ZMax = inf;
                end
                
                C2_clean = C2;
                C1_clean = C1;

                %If there are user specified z-bounds then apply them to
                %the matrices containing the z values to be plotted
                if(app.ZMin ~= -inf || app.ZMax ~= inf)
                    C2_clean(C2_clean<app.ZMin) = app.ZMin;
                    C2_clean(C2_clean>app.ZMax) = app.ZMax;
                    C1_clean(C1_clean<app.ZMin) = app.ZMin;
                    C1_clean(C1_clean>app.ZMax) = app.ZMax;
                end
                
                %Change the color based on the position of the color with
                %atan switch
                if (app.aTanColor == 1)
                    colorVariation = 1;
                else
                    colorVariation = 0;
                end
                

                % First plot graphing and labels
                 if colorVariation == 0
                    surf(app.UIAxes2,X,Y,C2_clean,C1,"EdgeColor","none");
                elseif colorVariation == 1
                    surf(app.UIAxes2,X,Y,C2_clean,L1,"EdgeColor","none");
                end
                
                if colorVariation == 0
                    str1 = 'Im W';
                elseif colorVariation == 1
                    str1 = 'Atan( Im W )';
                end

                %applys z-limits
               if app.ZMax ~= 0 || app.ZMin ~= 0
                    zlim(app.UIAxes2, [app.ZMin, app.ZMax]);
               else
                   zlim(app.UIAxes2, [-inf, inf]);
               end
            
                app.UIAxes2.XLabel.String = 'Re Z';
                app.UIAxes2.YLabel.String = 'Im Z';
                app.UIAxes2.ZLabel.String = 'Re W';
                c1=colorbar(app.UIAxes2);
                c1.Label.String=str1;
                colormap(app.UIAxes2,hsv)
            
                % Second plot graphing and labels
                if colorVariation == 0
                    surf(app.UIAxes3,X,Y,C1_clean,C2,"EdgeColor","none");
                elseif colorVariation == 1
                    surf(app.UIAxes3,X,Y,C1_clean,L2,"EdgeColor","none");
                end
                
                %applys z-limits
                if colorVariation == 0
                    str2 = 'Re W';
                elseif colorVariation == 1
                    str2 = 'Atan( Re W )';
                end
               
               if app.ZMax ~= 0 || app.ZMin ~= 0
                    zlim(app.UIAxes3, [app.ZMin, app.ZMax]);
               else
                   zlim(app.UIAxes3, [-inf, inf]);
               end
            
                
                app.UIAxes3.XLabel.String = 'Re Z';
                app.UIAxes3.YLabel.String = 'Im Z';
                app.UIAxes3.ZLabel.String = 'Im W';
                c2=colorbar(app.UIAxes3);
                c2.Label.String=str2;
                colormap(app.UIAxes3,hsv)

                time = toc;
                %Display the graphing time
                Message = sprintf('%f seconds\n',time);
                app.GraphingtimeEditField.Value = Message;
            catch
                
            end
        end

        %Check if a funciton has correctly used the dot operator
        function result = dotCheck(~, str)
            result = false;
            for i=2:length(str)
                if str(i-1) ~= '.' && (str(i) == '*' || str(i) == '/' || str(i) == '^')
                    result = true;
                    break;
                end
            end
        end

        %Provides an example use of domain coloring
        function DomainColorExample(app, L, Rx, Ry, Efunc)
                cla(app.UIAxes4, 'reset') 
                xlim(app.UIAxes4,'tight');
                ylim(app.UIAxes4, 'tight');
               
                % sets up variables and functions for the rest of the code
                f = Efunc;
                x=linspace(-Rx,Rx,L);
                y=linspace(Ry,-Ry,L);
                [X,Y] = meshgrid(x,y);

                Z=f((X) + 1i.*(Y));

                x=[-Rx Rx];
                y=[-Ry Ry];
                % argument and magnitude for every point in Z
                argZ=(angle(Z)+pi)/(2.*pi);
                magZ=mod(0.5.*log(1+real(Z).^2+imag(Z).^2),1)./1;
                
                % turns the arg and mag values into a set of colormaps
                argZmap=ind2rgb(uint8(255.*argZ),hsv);
                magZmap=ind2rgb(uint8(255.*magZ),gray);
                
                % plots the final result
                imagesc(x,-y,magZmap,"AlphaData",0.8,'Parent',app.UIAxes4)
                hold(app.UIAxes4, 'on')
                app.UIAxes4.XLabel.String = 'Real';
                app.UIAxes4.YLabel.String = 'Imaginary';
                imagesc(x,-y,argZmap,"AlphaData",0.55,'Parent',app.UIAxes4)
                app.UIAxes4.YDir = 'normal';
                hold(app.UIAxes4, 'off')
        end
     
        %derrivitive used for Newton's method
        function x = deriv(app,f,x0)
            h=10^-11;
            g = @(t) (f(t+h)-f(t))./h;
            x=g(x0);
        end
        
        %uses Newton's method to find roots/poles
        function nMethod(app,Rx,Ry)
            try
                %f is the user-inputted function
                f = app.func;
                if app.PolesSwitch.Value == "On"
                    isPole = true;
                    app.UITable.ColumnName = "Poles";
                else 
                    isPole = false;
                    app.UITable.ColumnName = "Roots";
                end
                
                %Takes the larger of the bounds and stores it for later
                if Rx>Ry
                   R=Rx;
                else
                   R=Ry;
                end
                
                %The poles of the function are the roots of one over the
                %function
                if isPole
                    f=@(z) 1./f(z);
                end
            
                % Creates an ellipse around the origin with an amount of
                % points that scales with the major axis length
                kMax=100;
                C=linspace(0,2.*pi,50.*R.^2);
                xCurr=Rx.*cos(C)+Ry.*1i.*sin(C);
            
                % Newton's Method performed on all points
                for i=1:kMax
                    xCurr=xCurr+1./(f(xCurr).*deriv(app,@(z) 1./f(z),xCurr));
                end

                % Rounds the output, deletes duplicate values, and deletes
                % values outside the visible space the user can see
                x=unique(xCurr);
                x=x(abs(real(x))<=Rx & abs(imag(x))<=Ry);
            
                % Gives a message if no roots or poles were found
                if isempty(x)
                    if isPole
                        x= "No poles found";
                        
                    else
                        x= "No roots found";
                        
                    end
                end

                % Makes the output display in rows rather than columns
                % because it looks better
                if(isnumeric(x))
                    x = unique(round(conj(x'),10));
                end
                % Display results as table
                d = array2table(x);
                app.UITable.Data = d;
                app.UITable.Visible = "on";
            catch
                
            end
        end
       
        %Provides an example use of complex surfaces
        function SurfaceExample(app, Res, Rx2, Ry2, Sfunc)
            cla(app.UIAxes4, 'reset');
            a=linspace(-Rx2,Rx2,Res);
            b=linspace(-Ry2,Ry2,Res);
            [X,Y]=meshgrid(a,b);
            Z = Sfunc;
            W=Z(X+1i.*Y);
            C1=imag(W);
            C2=real(W);
            L1=atan(C1);
            L2=atan(C2);

            surf(app.UIAxes4,X,Y,C1,C2,"EdgeColor","none");
            colormap(app.UIAxes4,hsv)

            app.UIAxes4.XLabel.String = 'Re Z';
            app.UIAxes4.YLabel.String = 'Im Z';
            app.UIAxes4.ZLabel.String = 'Im W';

        end
    end
    
    %The fucntions in this section are how the advanced features app
    % and main app interact with one another.
    %The exact use of each function should be self-obvious
    methods (Access = public)
        
        function colorWithArctan(app, val)
            app.aTanColor = val;
        end
        
        function colorVar(app, val)
            app.DomainColorVar = val;
        end

        function rStepVal(app, val)
            app.rStep = val;
        end

        function DCmoveCenter(app, re, im)
            app.ReOff = re;
            app.ImOff = im;
            if (re ~= 0 || im ~= 0)
                app.usedOff = true;
            end
        end

        function DCres(app, val)
            app.L = val;
        end

        function CSres(app, val)
            app.Res = val;
        end

        function CSmoveCenter(app, re, im)
            app.CSreOff = re;
            app.CSimOff = im;
        end
        
        function Zscale(app, min, max)
            app.ZMax = max;
            app.ZMin = min;
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RealScaleEditField
        function RealScaleEditFieldValueChanged(app, event)
            value = app.RealScaleEditField.Value;
            app.Rx= value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Make sure the function is using the dot operator correctly
            if app.FunctionzEditField.Value ~= "" && dotCheck(app, app.FunctionzEditField.Value)
                message = {'No dot operator!','Consider adding dot operator.'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end
            %Run the DomainColor function if the user has put in a
            %function or if a common funciton has been selected
            if (app.FunctionzEditField.Value ~= "" || app.CommonFunctionsDropDown.Value ~= "Select") 
                DomianColor(app, app.L,app.Rx, app.Ry);
            end
        end

        % Value changed function: ImaginaryScaleEditField
        function ImaginaryScaleEditFieldValueChanged(app, event)
            value = app.ImaginaryScaleEditField.Value;
            app.Ry= value;
        end

        % Value changed function: FunctionzEditField
        function FunctionzEditFieldValueChanged(app, event)
            %Check if the funciton for domain color is
            %valid and create an error message to display to the user
            try
            if(app.FunctionzEditField.Value ~= "") 
                app.func = str2func(['@(z) ' app.FunctionzEditField.Value ]);
                app.func(1);
            end
            catch
                uialert(app.UIFigure,'Invalid Funciton','Invalid Function');
            end
            
        end

        % Value changed function: CommonFunctionsDropDown
        function CommonFunctionsDropDownValueChanged(app, event)
            %Set the function for domain coloring to one of the inbuilt
            %functions or to the user difined funciton based on what has
            %been selected in the drop down menu
            switch app.CommonFunctionsDropDown.Value
                case 'Select'
                    if(app.FunctionzEditField.Value ~= "") 
                       app.func = str2func(['@(z) ' app.FunctionzEditField.Value ]);
                    end
                case 'Gamma'
                    app.func = @(z) gamma(app, z);
                case 'Zeta'
                    app.func = @(z) Rzeta(app, z);
                    
            end
        end

        % Value changed function: RealScaleEditField_2
        function RealScaleEditField_2ValueChanged(app, event)
            value = app.RealScaleEditField_2.Value;
            app.Rx2 = value;
        end

        % Value changed function: ImaginaryScaleEditField_2
        function ImaginaryScaleEditField_2ValueChanged(app, event)
            value = app.ImaginaryScaleEditField_2.Value;
            app.Ry2 = value;
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            %Make sure the function is using the dot operator correctly
            if app.FunctionzEditField_2.Value ~= "" && dotCheck(app, app.FunctionzEditField_2.Value)
                message = {'No dot operator!','Consider adding dot operator.'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end
            %Run the complexSurf function if the user has put in a
            %function or if a common funciton has been selected
            if (app.FunctionzEditField_2.Value ~= "" || app.CommonFunctionsDropDown_2.Value ~= "Select")
                complexSurf(app , app.Res, app.Rx2, app.Ry2);
            end
        end

        % Value changed function: FunctionzEditField_2
        function FunctionzEditField_2ValueChanged(app, event)
            %Check if the funciton for complex surfaces is
            %valid and create an error message to display to the user
            try
            if(app.FunctionzEditField_2.Value ~= "") 
                app.func2 = str2func(['@(z) ' app.FunctionzEditField_2.Value ]);
                app.func2(1);
            end
            catch
                uialert(app.UIFigure,'Invalid Funciton','Invalid Function');
            end
            
        end

        % Value changed function: CommonFunctionsDropDown_2
        function CommonFunctionsDropDown_2ValueChanged(app, event)
            %Set the function for complex surfaces to one of the inbuilt
            %functions or to the user difined funciton based on what has
            %been selected in the drop down menu
            switch app.CommonFunctionsDropDown_2.Value
                case 'Select'
                    if (app.FunctionzEditField.Value ~= "") 
                       app.func2 = str2func(['@(z) ' app.FunctionzEditField_2.Value ]);
                    end
                case 'Gamma'
                    app.func2 = @(z) gamma(app, z);
                case 'Zeta'
                    app.func2 = @(z) Rzeta(app, z);
            end
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            %Call the advanced app to gain acess to the domain coloring advanced features
            app.otherApp = advanced(app, 'd', app.aTanColor, app.L, app.Res, app.ImOff, app.ReOff, app.CSimOff, app.CSreOff, app.ZMin, app.ZMax, app.DomainColorVar, app.rStep);
        end

        % Button pushed function: AdvancedButton_2
        function AdvancedButton_2Pushed(app, event)
            %Call the advanced app to gain acess to the complex surfaces advanced features
            app.otherApp = advanced(app, 'c', app.aTanColor, app.L, app.Res, app.ImOff, app.ReOff, app.CSimOff, app.CSreOff, app.ZMin, app.ZMax,app.DomainColorVar, app.rStep);
        end

        % Button pushed function: TryItButton
        function TryItButtonPushed(app, event)
            DomainColorExample(app, 1000, 10, 10, @(z) log(z));
        end

        % Value changed function: RootandpolefindingSwitch
        function RootandpolefindingSwitchValueChanged(app, event)
            value = app.RootandpolefindingSwitch.Value;
            if value == "On"
                app.PolesSwitch.Visible = "on";
                app.GenerateButton.Visible = "on";
                app.HeightEditField.Visible = "on";
                app.WidthEditField.Visible = "on";
                app.HeightEditFieldLabel.Visible = "on";
                app.WidthEditFieldLabel.Visible = "on";
                app.PolesSwitchLabel.Visible = "on";
            else
                app.PolesSwitch.Visible = "off";
                app.GenerateButton.Visible = "off";
                app.HeightEditField.Visible = "off";
                app.WidthEditField.Visible = "off";
                app.HeightEditFieldLabel.Visible = "off";
                app.WidthEditFieldLabel.Visible = "off";
                app.PolesSwitchLabel.Visible = "off";
                app.UITable.Visible = "off";
            end
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            app.width = value;
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.height = value;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.UITable.Data = [];
            nMethod(app,app.width,app.height);
        end

        % Button pushed function: TryItButton_2
        function TryItButton_2Pushed(app, event)
            DomainColorExample(app, 1000, 10, 10, @(z) z.^2 - 1);
        end

        % Button pushed function: TryItButton_3
        function TryItButton_3Pushed(app, event)
            SurfaceExample(app, 500, 10 , 10, @(z) log(z));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DomainColoringTab = uitab(app.TabGroup);
            app.DomainColoringTab.Title = 'Domain Coloring';
            app.UIAxes = uiaxes(app.DomainColoringTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Real')
            ylabel(app.UIAxes, 'Imaginary')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.DomainColoringTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RealScaleEditFieldLabel = uilabel(app.DomainColoringTab);
            app.RealScaleEditFieldLabel.Text = 'Real-Scale';
            app.RealScaleEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.RealScaleEditField.ValueChangedFcn = createCallbackFcn(app, @RealScaleEditFieldValueChanged, true);
            app.RealScaleEditField.Value = 10;
            app.ImaginaryScaleEditFieldLabel = uilabel(app.DomainColoringTab);
            app.ImaginaryScaleEditFieldLabel.Text = 'Imaginary-Scale';
            app.ImaginaryScaleEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.ImaginaryScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ImaginaryScaleEditFieldValueChanged, true);
            app.ImaginaryScaleEditField.Value = 10;
            app.FunctionzLabel = uilabel(app.DomainColoringTab);
            app.FunctionzLabel.Text = 'Function (z):';
            app.FunctionzEditField = uieditfield(app.DomainColoringTab, 'text');
            app.FunctionzEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionzEditFieldValueChanged, true);
            app.CommonFunctionsDropDownLabel = uilabel(app.DomainColoringTab);
            app.CommonFunctionsDropDownLabel.Text = 'Common Functions';
            app.CommonFunctionsDropDown = uidropdown(app.DomainColoringTab);
            app.CommonFunctionsDropDown.Items = {'Select', 'Gamma', 'Zeta'};
            app.CommonFunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @CommonFunctionsDropDownValueChanged, true);
            app.CommonFunctionsDropDown.Value = 'Select';
            app.AdvancedButton = uibutton(app.DomainColoringTab, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.RootandpolefindingSwitchLabel = uilabel(app.DomainColoringTab);
            app.RootandpolefindingSwitchLabel.Text = 'Root and pole finding';
            app.RootandpolefindingSwitch = uiswitch(app.DomainColoringTab, 'slider');
            app.RootandpolefindingSwitch.ValueChangedFcn = createCallbackFcn(app, @RootandpolefindingSwitchValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.DomainColoringTab);
            app.WidthEditFieldLabel.Visible = 'off';
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.WidthEditField.Limits = [1 Inf];
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Visible = 'off';
            app.WidthEditField.Value = 10;
            app.HeightEditFieldLabel = uilabel(app.DomainColoringTab);
            app.HeightEditFieldLabel.Visible = 'off';
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.DomainColoringTab, 'numeric');
            app.HeightEditField.Limits = [1 Inf];
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Visible = 'off';
            app.HeightEditField.Value = 10;
            app.UITable = uitable(app.DomainColoringTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.PolesSwitchLabel = uilabel(app.DomainColoringTab);
            app.PolesSwitchLabel.Visible = 'off';
            app.PolesSwitchLabel.Text = 'Poles';
            app.PolesSwitch = uiswitch(app.DomainColoringTab, 'slider');
            app.PolesSwitch.Visible = 'off';
            app.GenerateButton = uibutton(app.DomainColoringTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Visible = 'off';
            app.GenerateButton.Text = 'Generate';
            app.GraphigtimeEditFieldLabel = uilabel(app.DomainColoringTab);
            app.GraphigtimeEditFieldLabel.Text = 'Graphig time';
            app.GraphigtimeEditField = uieditfield(app.DomainColoringTab, 'text');
            app.GraphigtimeEditField.Editable = 'off';
            app.ComplexSurfacesTab = uitab(app.TabGroup);
            app.ComplexSurfacesTab.Title = 'Complex Surfaces';
            app.UIAxes2 = uiaxes(app.ComplexSurfacesTab);
            title(app.UIAxes2, ' ')
            xlabel(app.UIAxes2, 'Real')
            ylabel(app.UIAxes2, 'Imaginary')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.ComplexSurfacesTab);
            title(app.UIAxes3, ' ')
            xlabel(app.UIAxes3, 'Real')
            ylabel(app.UIAxes3, 'Imaginary')
            zlabel(app.UIAxes3, 'Z')
            app.RealScaleEditField_2Label = uilabel(app.ComplexSurfacesTab);
            app.RealScaleEditField_2Label.Text = 'Real-Scale';
            app.RealScaleEditField_2 = uieditfield(app.ComplexSurfacesTab, 'numeric');
            app.RealScaleEditField_2.ValueChangedFcn = createCallbackFcn(app, @RealScaleEditField_2ValueChanged, true);
            app.RealScaleEditField_2.Value = 10;
            app.ImaginaryScaleEditField_2Label = uilabel(app.ComplexSurfacesTab);
            app.ImaginaryScaleEditField_2Label.Text = 'Imaginary-Scale';
            app.ImaginaryScaleEditField_2 = uieditfield(app.ComplexSurfacesTab, 'numeric');
            app.ImaginaryScaleEditField_2.ValueChangedFcn = createCallbackFcn(app, @ImaginaryScaleEditField_2ValueChanged, true);
            app.ImaginaryScaleEditField_2.Value = 10;
            app.RunButton_2 = uibutton(app.ComplexSurfacesTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.FunctionzLabel_2 = uilabel(app.ComplexSurfacesTab);
            app.FunctionzLabel_2.Text = 'Function (z):';
            app.FunctionzEditField_2 = uieditfield(app.ComplexSurfacesTab, 'text');
            app.FunctionzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FunctionzEditField_2ValueChanged, true);
            app.CommonFunctionsDropDown_2Label = uilabel(app.ComplexSurfacesTab);
            app.CommonFunctionsDropDown_2Label.Text = 'Common Functions';
            app.CommonFunctionsDropDown_2 = uidropdown(app.ComplexSurfacesTab);
            app.CommonFunctionsDropDown_2.Items = {'Select', 'Gamma', 'Zeta'};
            app.CommonFunctionsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CommonFunctionsDropDown_2ValueChanged, true);
            app.CommonFunctionsDropDown_2.Value = 'Select';
            app.AdvancedButton_2 = uibutton(app.ComplexSurfacesTab, 'push');
            app.AdvancedButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButton_2Pushed, true);
            app.AdvancedButton_2.Text = 'Advanced';
            app.GraphingtimeEditFieldLabel = uilabel(app.ComplexSurfacesTab);
            app.GraphingtimeEditFieldLabel.Text = 'Graphing time';
            app.GraphingtimeEditField = uieditfield(app.ComplexSurfacesTab, 'text');
            app.GraphingtimeEditField.Editable = 'off';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea_2 = uitextarea(app.HelpTab);
            app.TextArea_2.Value = {'Domain Coloring:'; ''; 'To use the most basic verion of domain coloring, simply type in your function (MATLAB syntax) or select one of the available common functions. '; ''; 'To change how "zoomed in" it is on the graph, change the Imaginary scale and Real Scale to change it for those respective axes.'; ''; 'In the advanced features menu:'; ''; 'Shift where the graph is centered by toggling the "shift center" button, then setting an offset from the origin in the boxes provided.'; ''; 'Change how the graph is colored with color variation. Variation 1 is the basic, which has magnitude lines drawn (where the brightness essentially resets) every rStep * e^k, where k is qan integer. Color Variation 2 is similar, but it does it every non-negative k. Color Variation 3 omits these lines altogether. '; ''; 'Change the value of rStep, which was mentioned in the previous feature.'; ''; ''; 'Complex Surfaces:'; ''; 'To use the most basic verion of complex surfaces, simply type in your function (MATLAB syntax) or select one of the available common functions. '; ''; 'To change how "zoomed in" it is on the graph, change the Imaginary scale and Real Scale to change it for those respective axes.'; ''; 'In the advanced features menu:'; ''; 'Shift where the graph is centered by toggling the "shift center" button, then setting an offset from the origin in the boxes provided.'; ''; 'Color the graph using Arctangent by simply toggling the switch so you can see changes in color that may otherwise be unnoticeable.'; ''; 'Change the minimum and maximum output "z-value" displayed so you can see detail in functions where certain outputs are much larger than others. Do this by toggling the switch and then entering your values.'; ''; 'ComplexGraphingFinal is made by Gabriel Cammack-Coleman, Cameron Miller, and Brycen Daniels'};
            app.ExamplesTab = uitab(app.TabGroup);
            app.ExamplesTab.Title = 'Examples';
            app.UIAxes4 = uiaxes(app.ExamplesTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.TryItButton = uibutton(app.ExamplesTab, 'push');
            app.TryItButton.ButtonPushedFcn = createCallbackFcn(app, @TryItButtonPushed, true);
            app.TryItButton.Text = 'Try It';
            app.TryItButton_2 = uibutton(app.ExamplesTab, 'push');
            app.TryItButton_2.ButtonPushedFcn = createCallbackFcn(app, @TryItButton_2Pushed, true);
            app.TryItButton_2.Text = 'Try It';
            app.TryItButton_3 = uibutton(app.ExamplesTab, 'push');
            app.TryItButton_3.ButtonPushedFcn = createCallbackFcn(app, @TryItButton_3Pushed, true);
            app.TryItButton_3.Text = 'Try It';
            app.logzTextAreaLabel = uilabel(app.ExamplesTab);
            app.logzTextAreaLabel.Text = 'log(z)';
            app.logzTextArea = uitextarea(app.ExamplesTab);
            app.logzTextArea.Editable = 'off';
            app.logzTextArea.Value = {'On the negative part of the real axis, we see a branch cut. This plot can only display the principle branch of a function, so there is a discontinuous break there. This is meant to hapen.'};
            app.z21TextAreaLabel = uilabel(app.ExamplesTab);
            app.z21TextAreaLabel.Text = 'z.^2 - 1';
            app.z21TextArea = uitextarea(app.ExamplesTab);
            app.z21TextArea.Editable = 'off';
            app.z21TextArea.Value = {'The two black dots near the middle of the plot are roots. The jumps in brightness occur every exponent of e.'};
            app.logzTextArea_2Label = uilabel(app.ExamplesTab);
            app.logzTextArea_2Label.Text = 'log(z) ';
            app.logzTextArea_2 = uitextarea(app.ExamplesTab);
            app.logzTextArea_2.Editable = 'off';
            app.logzTextArea_2.Value = {'The jump in the function is the branch cut mentioned before. Here we see that it only exists in the imaginary part of the function'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComplexGraphingFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end