classdef advanced < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DomainColorTab              matlab.ui.container.Tab
        RStepEditField              matlab.ui.control.NumericEditField
        RStepEditFieldLabel         matlab.ui.control.Label
        ColorVariationsButtonGroup  matlab.ui.container.ButtonGroup
        ColorVariation3Button       matlab.ui.control.RadioButton
        ColorVariation2Button       matlab.ui.control.RadioButton
        ColorVariation1Button       matlab.ui.control.RadioButton
        ResolutionEditField         matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        ReOffsetEditField           matlab.ui.control.NumericEditField
        ReOffsetEditFieldLabel      matlab.ui.control.Label
        ImOffsetEditField           matlab.ui.control.NumericEditField
        ImOffsetEditFieldLabel      matlab.ui.control.Label
        MoveCenterSwitch            matlab.ui.control.Switch
        MoveCenterSwitchLabel       matlab.ui.control.Label
        ComplexSurfaceTab           matlab.ui.container.Tab
        ZMaxEditField               matlab.ui.control.NumericEditField
        ZMaxEditFieldLabel          matlab.ui.control.Label
        ZMinEditField               matlab.ui.control.NumericEditField
        ZMinEditFieldLabel          matlab.ui.control.Label
        ChangeZScaleSwitch          matlab.ui.control.Switch
        ChangeZScaleSwitchLabel     matlab.ui.control.Label
        ResolutionEditField_2       matlab.ui.control.NumericEditField
        ResolutionEditField_2Label  matlab.ui.control.Label
        ReOffsetEditField_2         matlab.ui.control.NumericEditField
        ReOffsetEditField_2Label    matlab.ui.control.Label
        ImOffsetEditField_2         matlab.ui.control.NumericEditField
        ImOffsetEditField_2Label    matlab.ui.control.Label
        MoveCenterSwitch_2          matlab.ui.control.Switch
        MoveCenterSwitch_2Label     matlab.ui.control.Label
        ColorwithArctanSwitch       matlab.ui.control.Switch
        ColorwithArctanSwitchLabel  matlab.ui.control.Label
    end

    %Brycen Daniels, Gabriel Cammack-Coleman, and Cameron Miller

    properties (Access = private)
        MainApp; %Stores the ComplexGraphing app in a callable way
        arctan = 0; %controls color with arctan
        DCres = 1000; %controls resolution of domain coloring
        CSres = 500; %controls resolution of comples surfaces
        DCIMoff = 0; %controls imaginary offset of domain coloring
        DCREoff = 0; %controls real offset of domain coloring
        CSIMoff = 0; %controls imaginary offset of complex surfaces
        CSREoff = 0; %controls real offset of complex surfaces
        Zmin = 0; %controls minimum output in complex surfaces
        Zmax = 0; %controls maximum output in complex surfaces
        DomainColorVar = 1; %controls which color variation is being used
        rStep = 1; %changes where magnatude lines are drawn
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, start, arctan, DCres, CSres, DCIMoff, DCREoff, CSIMoff, CSREoff, Zmin, Zmax, DomainColorVar, rStep)
            app.MainApp = MainApp;
            
            %Changes the starting tab depending upon where it was opened
            if (start == 'c')
                app.TabGroup.SelectedTab = app.ComplexSurfaceTab;
            else
                app.TabGroup.SelectedTab = app.DomainColorTab;
            end
        
            %Defaults the value for arctangent coloring
            app.arctan = arctan;
            if (arctan == 1)
                app.ColorwithArctanSwitch.Value = "On";
            else
                app.ColorwithArctanSwitch.Value = "Off";
            end

            %Defaults the value for the resoultions
            app.DCres = DCres;
            app.ResolutionEditField.Value = DCres;
            app.CSres = CSres;
            app.ResolutionEditField_2.Value = CSres;

            %Defaults the value for Domain Coloring center shift
            app.DCIMoff = DCIMoff;
            app.DCREoff = DCREoff;
            if (DCIMoff == 0 && DCREoff == 0)
                app.MoveCenterSwitch.Value = "Off";
                app.ReOffsetEditField.Visible = "off";
                app.ImOffsetEditField.Visible = "off";
                app.ReOffsetEditFieldLabel.Visible = "off";
                app.ImOffsetEditFieldLabel.Visible = "off";
            else
                app.MoveCenterSwitch.Value = "On";
                app.ReOffsetEditField.Visible = "on";
                app.ImOffsetEditField.Visible = "on";
                app.ReOffsetEditFieldLabel.Visible = "on";
                app.ImOffsetEditFieldLabel.Visible = "on";
                app.ReOffsetEditField.Value = DCREoff;
                app.ImOffsetEditField.Value = DCIMoff;
            end

            %Defaults the value for complex surface center shift
            app.CSIMoff = CSIMoff;
            app.CSREoff = CSREoff;
            if (CSIMoff == 0 && CSREoff == 0)
                app.MoveCenterSwitch_2.Value = "Off";
                app.ReOffsetEditField_2.Visible = "off";
                app.ImOffsetEditField_2.Visible = "off";
                app.ReOffsetEditField_2Label.Visible = "off";
                app.ImOffsetEditField_2Label.Visible = "off";
            else
                app.MoveCenterSwitch_2.Value = "On";
                app.ReOffsetEditField_2.Visible = "on";
                app.ImOffsetEditField_2.Visible = "on";
                app.ReOffsetEditField_2Label.Visible = "on";
                app.ImOffsetEditField_2Label.Visible = "on";
                app.ReOffsetEditField_2.Value = CSREoff;
                app.ImOffsetEditField_2.Value = CSIMoff;
            end

            %Defaults the value for Z-scale changing
            app.Zmin = Zmin;
            app.Zmax = Zmax;
            if (Zmin == 0 && Zmax == 0)
                app.ChangeZScaleSwitch.Value = "Off";
                app.ZMaxEditField.Visible = "off";
                app.ZMinEditField.Visible = "off";
                app.ZMaxEditFieldLabel.Visible = "off";
                app.ZMinEditFieldLabel.Visible = "off";
            else
                app.ChangeZScaleSwitch.Value = "On";
                app.ZMaxEditField.Visible = "on";
                app.ZMinEditField.Visible = "on";
                app.ZMaxEditFieldLabel.Visible = "on";
                app.ZMinEditFieldLabel.Visible = "on";
                app.ZMaxEditField.Value = Zmax;
                app.ZMinEditField.Value = Zmin;
            end

            %Defaults the value for color variation
            app.DomainColorVar = DomainColorVar;
            switch (DomainColorVar)
                case 1
                    app.ColorVariation1Button.Value = 1;
                    app.ColorVariation2Button.Value = 0;
                    app.ColorVariation3Button.Value = 0;
                case 2
                    app.ColorVariation1Button.Value = 0;
                    app.ColorVariation2Button.Value = 1;
                    app.ColorVariation3Button.Value = 0;
                case 3
                    app.ColorVariation1Button.Value = 0;
                    app.ColorVariation2Button.Value = 0;
                    app.ColorVariation3Button.Value = 1;
            end

            %Defaults the value for rStep
            app.rStep = rStep;
            app.RStepEditField.Value = app.rStep;
            
        end

        % Value changed function: ColorwithArctanSwitch
        function ColorwithArctanSwitchValueChanged(app, event)
            value = app.ColorwithArctanSwitch.Value;

            %Sends the value back to the main app
            if value == "On"
                app.MainApp.colorWithArctan(1);
            else 
                app.MainApp.colorWithArctan(0);
            end
        end

        % Value changed function: MoveCenterSwitch
        function MoveCenterSwitchValueChanged(app, event)
            value = app.MoveCenterSwitch.Value;
            if (value == "Off")
                app.ReOffsetEditField.Visible = "off";
                app.ImOffsetEditField.Visible = "off";
                app.ReOffsetEditFieldLabel.Visible = "off";
                app.ImOffsetEditFieldLabel.Visible = "off";
                %Updates the values and sends them to the main app
                app.DCIMoff = 0;
                app.DCREoff = 0;
                app.MainApp.DCmoveCenter(0, 0);
            else
                app.ReOffsetEditField.Visible = "on";
                app.ImOffsetEditField.Visible = "on";
                app.ReOffsetEditFieldLabel.Visible = "on";
                app.ImOffsetEditFieldLabel.Visible = "on";
            end
        end

        % Value changed function: ImOffsetEditField
        function ImOffsetEditFieldValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.ImOffsetEditField.Value;
            app.DCIMoff = value;
            app.MainApp.DCmoveCenter(app.DCREoff, app.DCIMoff);
        end

        % Value changed function: ReOffsetEditField
        function ReOffsetEditFieldValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.ReOffsetEditField.Value;
            app.DCREoff = value;
            app.MainApp.DCmoveCenter(app.DCREoff, app.DCIMoff);
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.ResolutionEditField.Value;
            app.DCres = value;
            app.MainApp.DCres(value);
        end

        % Value changed function: MoveCenterSwitch_2
        function MoveCenterSwitch_2ValueChanged(app, event)
            value = app.MoveCenterSwitch_2.Value;
            if (value == "Off")
                app.ReOffsetEditField_2.Visible = "off";
                app.ImOffsetEditField_2.Visible = "off";
                app.ReOffsetEditField_2Label.Visible = "off";
                app.ImOffsetEditField_2Label.Visible = "off";
                %Updates the values and sends them to the main app
                app.CSIMoff = 0;
                app.CSREoff = 0;
                app.MainApp.CSmoveCenter(0, 0);
            else
                app.ReOffsetEditField_2.Visible = "on";
                app.ImOffsetEditField_2.Visible = "on";
                app.ReOffsetEditField_2Label.Visible = "on";
                app.ImOffsetEditField_2Label.Visible = "on";
            end
        end

        % Value changed function: ImOffsetEditField_2
        function ImOffsetEditField_2ValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.ImOffsetEditField_2.Value;
            app.CSIMoff = value;
            app.MainApp.CSmoveCenter(app.CSREoff, app.CSIMoff);
        end

        % Value changed function: ReOffsetEditField_2
        function ReOffsetEditField_2ValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.ReOffsetEditField_2.Value;
            app.CSREoff = value;
            app.MainApp.CSmoveCenter(app.CSREoff, app.CSIMoff);
        end

        % Value changed function: ResolutionEditField_2
        function ResolutionEditField_2ValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.ResolutionEditField_2.Value;
            app.CSres = value;
            app.MainApp.CSres(value);
        end

        % Value changed function: ChangeZScaleSwitch
        function ChangeZScaleSwitchValueChanged(app, event)
            value = app.ChangeZScaleSwitch.Value;
            if (value == "Off")
                app.ZMaxEditField.Visible = "off";
                app.ZMinEditField.Visible = "off";
                app.ZMaxEditFieldLabel.Visible = "off";
                app.ZMinEditFieldLabel.Visible = "off";
                %Sends value to the main app
                app.MainApp.Zscale(0, 0);
            else
                app.ZMaxEditField.Visible = "on";
                app.ZMinEditField.Visible = "on";
                app.ZMaxEditFieldLabel.Visible = "on";
                app.ZMinEditFieldLabel.Visible = "on";
            end
        end

        % Value changed function: ZMinEditField
        function ZMinEditFieldValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.ZMinEditField.Value;
            app.Zmin = value;
            app.MainApp.Zscale(app.Zmin, app.Zmax);
        end

        % Value changed function: ZMaxEditField
        function ZMaxEditFieldValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.ZMaxEditField.Value;
            app.Zmax = value;
            app.MainApp.Zscale(app.Zmin, app.Zmax);
        end

        % Selection changed function: ColorVariationsButtonGroup
        function ColorVariationsButtonGroupSelectionChanged(app, event)
                %Sends value to the main app
                if app.ColorVariation1Button.Value == 1
                    app.MainApp.colorVar(1);
                elseif app.ColorVariation2Button.Value == 1
                    app.MainApp.colorVar(2);
                elseif app.ColorVariation3Button.Value == 1
                    app.MainApp.colorVar(3);
                end
        end

        % Value changed function: RStepEditField
        function RStepEditFieldValueChanged(app, event)
            %Updates the value and sends it to the main app
            value = app.RStepEditField.Value;
            app.MainApp.rStepVal(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DomainColorTab = uitab(app.TabGroup);
            app.DomainColorTab.Title = 'Domain Color';
            app.MoveCenterSwitchLabel = uilabel(app.DomainColorTab);
            app.MoveCenterSwitchLabel.Text = 'Move Center';
            app.MoveCenterSwitch = uiswitch(app.DomainColorTab, 'slider');
            app.MoveCenterSwitch.ValueChangedFcn = createCallbackFcn(app, @MoveCenterSwitchValueChanged, true);
            app.ImOffsetEditFieldLabel = uilabel(app.DomainColorTab);
            app.ImOffsetEditFieldLabel.Visible = 'off';
            app.ImOffsetEditFieldLabel.Text = 'Im Offset';
            app.ImOffsetEditField = uieditfield(app.DomainColorTab, 'numeric');
            app.ImOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @ImOffsetEditFieldValueChanged, true);
            app.ImOffsetEditField.Visible = 'off';
            app.ReOffsetEditFieldLabel = uilabel(app.DomainColorTab);
            app.ReOffsetEditFieldLabel.Visible = 'off';
            app.ReOffsetEditFieldLabel.Text = 'Re Offset';
            app.ReOffsetEditField = uieditfield(app.DomainColorTab, 'numeric');
            app.ReOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @ReOffsetEditFieldValueChanged, true);
            app.ReOffsetEditField.Visible = 'off';
            app.ResolutionEditFieldLabel = uilabel(app.DomainColorTab);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.DomainColorTab, 'numeric');
            app.ResolutionEditField.Limits = [0 3000];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 1000;
            app.ColorVariationsButtonGroup = uibuttongroup(app.DomainColorTab);
            app.ColorVariationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorVariationsButtonGroupSelectionChanged, true);
            app.ColorVariationsButtonGroup.Title = 'Color Variations';
            app.ColorVariation1Button = uiradiobutton(app.ColorVariationsButtonGroup);
            app.ColorVariation1Button.Text = 'Color Variation 1';
            app.ColorVariation2Button = uiradiobutton(app.ColorVariationsButtonGroup);
            app.ColorVariation2Button.Text = 'Color Variation 2';
            app.ColorVariation2Button.Value = true;
            app.ColorVariation3Button = uiradiobutton(app.ColorVariationsButtonGroup);
            app.ColorVariation3Button.Text = 'Color Variation 3';
            app.RStepEditFieldLabel = uilabel(app.DomainColorTab);
            app.RStepEditFieldLabel.Text = 'R-Step';
            app.RStepEditField = uieditfield(app.DomainColorTab, 'numeric');
            app.RStepEditField.ValueChangedFcn = createCallbackFcn(app, @RStepEditFieldValueChanged, true);
            app.RStepEditField.Value = 1;
            app.ComplexSurfaceTab = uitab(app.TabGroup);
            app.ComplexSurfaceTab.Title = 'Complex Surface';
            app.ColorwithArctanSwitchLabel = uilabel(app.ComplexSurfaceTab);
            app.ColorwithArctanSwitchLabel.Text = 'Color with Arctan';
            app.ColorwithArctanSwitch = uiswitch(app.ComplexSurfaceTab, 'slider');
            app.ColorwithArctanSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorwithArctanSwitchValueChanged, true);
            app.ColorwithArctanSwitch.Value = 'On';
            app.MoveCenterSwitch_2Label = uilabel(app.ComplexSurfaceTab);
            app.MoveCenterSwitch_2Label.Text = 'Move Center';
            app.MoveCenterSwitch_2 = uiswitch(app.ComplexSurfaceTab, 'slider');
            app.MoveCenterSwitch_2.ValueChangedFcn = createCallbackFcn(app, @MoveCenterSwitch_2ValueChanged, true);
            app.ImOffsetEditField_2Label = uilabel(app.ComplexSurfaceTab);
            app.ImOffsetEditField_2Label.Visible = 'off';
            app.ImOffsetEditField_2Label.Text = 'Im Offset';
            app.ImOffsetEditField_2 = uieditfield(app.ComplexSurfaceTab, 'numeric');
            app.ImOffsetEditField_2.ValueChangedFcn = createCallbackFcn(app, @ImOffsetEditField_2ValueChanged, true);
            app.ImOffsetEditField_2.Visible = 'off';
            app.ReOffsetEditField_2Label = uilabel(app.ComplexSurfaceTab);
            app.ReOffsetEditField_2Label.Visible = 'off';
            app.ReOffsetEditField_2Label.Text = 'Re Offset';
            app.ReOffsetEditField_2 = uieditfield(app.ComplexSurfaceTab, 'numeric');
            app.ReOffsetEditField_2.ValueChangedFcn = createCallbackFcn(app, @ReOffsetEditField_2ValueChanged, true);
            app.ReOffsetEditField_2.Visible = 'off';
            app.ResolutionEditField_2Label = uilabel(app.ComplexSurfaceTab);
            app.ResolutionEditField_2Label.Text = 'Resolution';
            app.ResolutionEditField_2 = uieditfield(app.ComplexSurfaceTab, 'numeric');
            app.ResolutionEditField_2.Limits = [0 1000];
            app.ResolutionEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditField_2ValueChanged, true);
            app.ResolutionEditField_2.Value = 500;
            app.ChangeZScaleSwitchLabel = uilabel(app.ComplexSurfaceTab);
            app.ChangeZScaleSwitchLabel.Text = 'Change Z Scale';
            app.ChangeZScaleSwitch = uiswitch(app.ComplexSurfaceTab, 'slider');
            app.ChangeZScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @ChangeZScaleSwitchValueChanged, true);
            app.ZMinEditFieldLabel = uilabel(app.ComplexSurfaceTab);
            app.ZMinEditFieldLabel.Visible = 'off';
            app.ZMinEditFieldLabel.Text = 'Z-Min';
            app.ZMinEditField = uieditfield(app.ComplexSurfaceTab, 'numeric');
            app.ZMinEditField.ValueChangedFcn = createCallbackFcn(app, @ZMinEditFieldValueChanged, true);
            app.ZMinEditField.Visible = 'off';
            app.ZMaxEditFieldLabel = uilabel(app.ComplexSurfaceTab);
            app.ZMaxEditFieldLabel.Visible = 'off';
            app.ZMaxEditFieldLabel.Text = 'Z-Max';
            app.ZMaxEditField = uieditfield(app.ComplexSurfaceTab, 'numeric');
            app.ZMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ZMaxEditFieldValueChanged, true);
            app.ZMaxEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = advanced(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end